classdef SuperRes_v4_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        detection                 matlab.ui.container.Menu
        CheckqualityMenu          matlab.ui.container.Menu
        driftcorrection           matlab.ui.container.Menu
        colorshift                matlab.ui.container.Menu
        correctdetections         matlab.ui.container.Menu
        ConcatenatedataMenu       matlab.ui.container.Menu
        AnalysisMenu              matlab.ui.container.Menu
        SegmentationMenu          matlab.ui.container.Menu
        ClusteringMenu            matlab.ui.container.Menu
        ColocalizationMenu        matlab.ui.container.Menu
        help                      matlab.ui.container.Menu
        SuperRes_v4suiteMenu      matlab.ui.container.Menu
        DetectionMenu             matlab.ui.container.Menu
        ManagedetectionsMenu      matlab.ui.container.Menu
        SMLMdataanalysisMenu      matlab.ui.container.Menu
        TrackinganddiffusionMenu  matlab.ui.container.Menu
        VisualizationMenu         matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        VisualizationTab          matlab.ui.container.Tab
        DatainmCheckBox           matlab.ui.control.CheckBox
        filename2                 matlab.ui.control.Label
        filename1                 matlab.ui.control.Label
        zoompushbutton            matlab.ui.control.Button
        uipanel1                  matlab.ui.container.Panel
        LUTgrayCheckBox           matlab.ui.control.CheckBox
        LUThotCheckBox            matlab.ui.control.CheckBox
        szpx                      matlab.ui.control.EditField
        text8                     matlab.ui.control.Label
        PALMszpx                  matlab.ui.control.EditField
        text1                     matlab.ui.control.Label
        widthgaussiansmooth       matlab.ui.control.EditField
        text4                     matlab.ui.control.Label
        saveimagepushbutton       matlab.ui.control.Button
        renderedpushbutton        matlab.ui.control.Button
        uipanel2                  matlab.ui.container.Panel
        pointillisticpushbutton   matlab.ui.control.Button
        dotsize                   matlab.ui.control.EditField
        text5                     matlab.ui.control.Label
        savematpushbutton         matlab.ui.control.Button
        text6                     matlab.ui.control.Label
        alpha1                    matlab.ui.control.EditField
        alpha2                    matlab.ui.control.EditField
        text7                     matlab.ui.control.Label
        filemergepushbutton       matlab.ui.control.Button
        selectfilepushbutton      matlab.ui.control.Button
        DetectionTab              matlab.ui.container.Tab
        WidthfittingCheckBox      matlab.ui.control.CheckBox
        pixel                     matlab.ui.control.EditField
        text23                    matlab.ui.control.Label
        slider3                   matlab.ui.control.Slider
        text22                    matlab.ui.control.Label
        alphavalue                matlab.ui.control.EditField
        defloops                  matlab.ui.control.EditField
        text21                    matlab.ui.control.Label
        calibratepushbutton       matlab.ui.control.Button
        text14                    matlab.ui.control.Label
        gaussrad                  matlab.ui.control.EditField
        threshold                 matlab.ui.control.EditField
        text15                    matlab.ui.control.Label
        text20                    matlab.ui.control.Label
        windsize                  matlab.ui.control.EditField
        text19                    matlab.ui.control.Label
        moviefile                 matlab.ui.control.Label
        gopushbutton              matlab.ui.control.Button
        loadfiles                 matlab.ui.control.Button
        TrackinganddiffusionTab   matlab.ui.container.Tab
        visualize                 matlab.ui.control.Button
        localization              matlab.ui.control.Button
        DiffusionanalysisPanel    matlab.ui.container.Panel
        windowPc                  matlab.ui.control.EditField
        text38_3                  matlab.ui.control.Label
        minpointsD                matlab.ui.control.EditField
        text40_2                  matlab.ui.control.Label
        maxtlagmsd                matlab.ui.control.EditField
        text38_2                  matlab.ui.control.Label
        compile                   matlab.ui.control.Button
        trajindivpushbutton       matlab.ui.control.Button
        avtimewindow              matlab.ui.control.EditField
        uipanel9                  matlab.ui.container.Panel
        ReconnectonlyButton       matlab.ui.control.Button
        maxblktime                matlab.ui.control.EditField
        text44                    matlab.ui.control.Label
        maxdist                   matlab.ui.control.EditField
        text45                    matlab.ui.control.Label
        minlength                 matlab.ui.control.EditField
        text46                    matlab.ui.control.Label
        maxdisttlag1              matlab.ui.control.EditField
        text47                    matlab.ui.control.Label
        text43                    matlab.ui.control.Label
        weightD                   matlab.ui.control.EditField
        text41                    matlab.ui.control.Label
        resdiam                   matlab.ui.control.EditField
        text39                    matlab.ui.control.Label
        blkprob                   matlab.ui.control.EditField
        text42                    matlab.ui.control.Label
        maxcomp                   matlab.ui.control.EditField
        text40                    matlab.ui.control.Label
        maxD                      matlab.ui.control.EditField
        text38                    matlab.ui.control.Label
        text37                    matlab.ui.control.Label
        text36                    matlab.ui.control.Label
        till                      matlab.ui.control.EditField
        text35                    matlab.ui.control.Label
        pxsz                      matlab.ui.control.EditField
        text34                    matlab.ui.control.Label
        loadpkpushbutton          matlab.ui.control.Button
    end

    
    methods (Access = private)
        function [results, resultsmorpho] = analysisclusters(app, handles, peak, mask, filename, mindetec, PALMszpx)
                     
            qstring='Use ROIs?';
            button = questdlg(qstring);
            if strcmp(button,'Yes')
                rois=1;
                disp(['ROI file ',filename,'.rgn'])
            else
                rois=0;
            end
            
            X_mu = peak(:,2); % positions single mol converted to rendered image
            Y_mu = peak(:,3);
            fr = peak(:,1);
            alpha = peak(:,4); %???
            
            currentdir=cd;
            
            results=[];
            resultsmorpho=[];
            
            if isempty(mask)==0
            
                roundx=floor(peak(:,2))+1;
                roundy=floor(peak(:,3))+1;% rendered
                waitbarhandle=waitbar( 0,'Please wait...','Name','Colocalization of objects') ;
            
                %% ROIS?
                rois2=0;
                if rois==1
                    if exist([filename,'.rgn'])==2 %!!!!!!!!!!!!!
                        data=importdata([filename,'.rgn']); %!!!!!!!!!!!
                        if isstruct(data)
                            roifile=data.coord;
                        else
                            roifile=data;
                        end
                        disp(['Loading regions from ',filename,'.rgn'])
                        cnt=1;
                        rois2=1;
                        roi=zeros(size(mask,1),size(mask,2));
                    else
                        rois2=0;
                        disp(['No region in ',filename,' image'])
                        logical=0;
                    end
                else
                    cnt=0;
                end
            
                logicalrois=1;
            
                while logicalrois
                    labeled=[];
                    if rois2==1
                       x=roifile{cnt}(:,1);
                        y=roifile{cnt}(:,2);
            
                        indexx=find(x(:)<0);
                        if isempty(indexx)==0
                            x(indexx)=1;
                        end
                        indexx=find(x(:)>size(roi,2));
                        if isempty(indexx)==0
                            x(indexx)=size(roi,2);
                        end
                        indexy=find(y(:)<0);
                        if isempty(indexy)==0
                            y(indexy)=1;
                        end
                        indexy=find(y(:)>size(roi,1));
                        if isempty(indexy)==0
                            y(indexy)=size(roi,1);
                        end
            
                        mask=double(mask);
                        roi=roipoly(roi,x,y);
                        roiimage=mask.*roi; %!!!!!!!!!!!!!!!!!!!!!!!!!!!
                        level = graythresh(roiimage);
                        BWM = im2bw(roiimage,level);
            
                        [labeled,nb_syn] = bwlabel(BWM,4);
            
                    else
                        [labeled,nb_clu] = bwlabel(mask,4);
                        labeled2=[];
                        cnt=1;
                    end
            
                    area=regionprops(labeled,'Area');
                    stat=regionprops(labeled,'All');
            
                    % clusters groups of points
                    L=size(area,1);
                    for n=1:L
                            D=size(stat(n).PixelList,1);
                            m=1;
                            count=0;
                            sommealpha=0;
                            while m<=D
                                px=stat(n).PixelList(m,:);
                                indexx=find(roundx(:)==px(1));
                                indexy=find(roundy(indexx)==px(2));
                                if isempty(indexy)==0
                                    count=count+size(indexy,1);
                                    for t=1:size(indexy,1)
                                        if isnan(alpha(indexy(t)))==0
                                            sommealpha=sommealpha+alpha(indexy(t));
                                        end
                                    end
                                end
                                m=m+1;
                            end
                            if exist('waitbarhandle')
                                waitbar(n/L,waitbarhandle,['Cluster # ',num2str(n)]);
                            end
                            if count<mindetec
                            else
                                areamicron=area(n).Area *(PALMszpx^2);
                                MajorAxisLength=stat(n).MajorAxisLength*PALMszpx;
                                MinorAxisLength=stat(n).MinorAxisLength*PALMszpx;
                                results=[results; cnt n count areamicron count/areamicron sommealpha sommealpha/count MajorAxisLength MinorAxisLength];
                               % %%!! ROI#  cluster #  counts  area         counts/area    total intensity   average intensity   position MajorAxisLength   MinorAxisLength
            
                            end
                    end
            
                    if rois==0
                        logicalrois=0;
                    else
                        if rois2==1 && cnt<size(roifile,2)
                            logicalrois=1;
                            cnt=cnt+1;
                        else
                            logicalrois=0;
                        end
                    end
                end %while
            end
            
            if exist('waitbarhandle')
                close(waitbarhandle);
            end
        end
        
        function imagedata = createimagecorr(app, peak, accu, szpx)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Auxiliar functions
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
              maxy=floor(max(peak(:,3)));
            maxx=floor(max(peak(:,2)));
            
            % in µm
            peak(:,2)=peak(:,2)*szpx/accu; %conversion to new pixel size
            peak(:,3)=peak(:,3)*szpx/accu; %conversion to new pixel size
            imagedata=zeros(ceil(max(peak(:,3))),ceil(max(peak(:,2))));
            
            maxy=floor(max(peak(:,3)));
            %maxx=floor(max(peak(:,2)))
            
            
            for i=1:size(peak,1)
                roundx=floor(peak(i,2));
                if roundx<1; roundx=1;    end
                roundy=floor(peak(i,3));
                if roundy<1; roundy=1;    end
                % invert y rendered
                roundy=abs(roundy-maxy)+1;
            
                imagedata(roundy,roundx)=1;
            end
        end
        
        function M = flagmatrixelements(app, M, vectorindices)
            siz = size(M);
            aux = M(:);
            aux(vectorindices) = 1;
            M = reshape(aux,siz);
        end
        
        function I = image_ch(app, varargin)
            xx1 = varargin{1};
            yy1 = varargin{2};
            I = varargin{3};
            pct_sat = 0;
            if ndims(I)==2
                if nargin>3
                    clims = varargin{4};
                else
                    Imax = prctile(I(:),100-pct_sat);
                    Imin = min(I(:));
                    clims = [Imin Imax];
                end
                if any(isnan(clims)) || clims(2)<=clims(1)
                    warning(['clims (=',num2str(clims),')! Using [0,1] instead !']);
                    clims = [0 1];
                end
                I = imagesc(xx1,yy1,I,clims);
            else
                image(xx1,yy1,I);
            end
        end
        
        function ys = movingwindowfilter(app, y, span, Nmin, fhandle)
            % Filter over moving windows
            
            
            ys = NaN(size(y));
            N = length(y);
            left = floor((span-1)/2);
            right = left;
            for i=1:N
                ileft = max(1,i-left);
                iright = min(i+right,N);
                window = ileft:iright;
                aux = y(window);
                aux1 = aux(~isnan(aux));
                if length(aux1)>Nmin
                    ys(i) = fhandle(aux1);
                end
            end
        end
        
        function [x, y, fr, alpha, radius, sigma, blink, ratio, z, test1, test2, ffname, filename, control] = openPALMdata(app, handles, px_mu, file2, ffname)
            
            control=1;
            x=[];
            y=[];
            fr=[];
            alpha=[];
            radius=[];
            sigma=[];
            blink=[];
            ratio=[];
            z=[];
            test1=[];
            test2=[];
            
            message=msgbox('Please wait','Data','Warn');
            
            if nargin<4
                [filename, pathname] = uigetfile('*.mat','Select .mat file containing detection results');
                if filename==0
                    if file2==1
                        control=0;
                    end
                   % close(message)
                    ffname=[];
                    filename=[];
                    return
                end
                ffname = fullfile(pathname,filename);
            else
            end
            
            [pathstr, filename, ext] = fileparts(ffname);
            
                if strcmp(ext,'.mat')
                    S = load(ffname);
                    if isfield(S,'matrice_results')
                        %disp('File without tracking information');
            
                        aux = S.matrice_results;
                        clear S;
                        fr = aux(1,:); fr = fr(:);
                        x = aux(3,:);
                        x = x(:);
                        y = aux(2,:);
                        y = y(:);
                        alpha = aux(4,:); alpha = alpha(:);
                        if size(aux,1)>4
                            radius=(aux(5,:));
                        else
                            radius=zeros(size(x,2),1);
                        end
                        if size(aux,1)>5
                            sigma=aux(6,:)';
                            blink=aux(7,:)';
                            ratio=aux(8,:)';
                            z=aux(9,:)';
                            test1=aux(10,:)';
                            test2=aux(11,:)';
                        else
                            sigma=zeros(size(x,2),1);
                             blink=zeros(size(x,2),1);
                            ratio=zeros(size(x,2),1);
                            z=zeros(size(x,2),1);
                            test1=zeros(size(x,2),1);
                            test2=zeros(size(x,2),1);
                        end
            
                    elseif isfield(S,'alpha') && isfield(S,'fr') && isfield(S,'x') && isfield(S,'y')
            
                        x = S.x; % * px_mu;
                        x = x(:);
                        y = S.y; % * px_mu;
                        y = y(:);
                        alpha = S.alpha; alpha = alpha(:);
                        fr = S.fr; fr = fr(:);
                        if isfield(S,'radius')
                            radius=S.radius;
                        else
                            radius=zeros(size(x,2));
                        end
                        sigma=zeros(size(x,2));
                        blink=zeros(size(x,2));
                        ratio=zeros(size(x,2));
                        z=zeros(size(x,2));
                        test1=zeros(size(x,2));
                        test2=zeros(size(x,2));
            
                    else
                        %validfile = 0;
                        %             errordlg('This file is not valid !','Warning','modal');
                        disp('No data loaded');
                        if file2==1
                            control=0;
                        end
                        return
                    end
                end
            %end
            close(message)
            
            guidata(gcbo,handles) ;
        end
        
        function perspectiveradiobutton_Callback(app, hObject, eventdata, handles)
            code=get(hObject, 'value');
            if code==1;
                set(handles.sideviewsradiobutton,'Value',0);
            end
        end
        
        function newselect = pickpointsfast2(app, BW, xi, yi, szpx, accu, data)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
             %conversion to new pixel size
            data(:,2)=data(:,2)*szpx/accu;
            data(:,3)=data(:,3)*szpx/accu;
            
            %invert y
            %data(:,3)=abs(data(:,3)-(max(data(:,3))))+1 ;
            
            index1=find(data(:,2)>min(xi) & data(:,2)<max(xi));
            aux=data(index1,:);
            index2=find(aux(:,3)>min(yi) & aux(:,3)<max(yi));
            select=aux(index2,:);
            clear aux index1 index2
            
            count=1;
            newselect=[];
            
            for it=1:size(select,1)
                tx=round(select(it,3)); % x
                ty=round(select(it,2)); %y
                if tx>size(BW,1);          tx=size(BW,1);      end
                if ty>size(BW,2);         ty=size(BW,2);     end
                if tx<1;          tx=1;      end
                if ty<1;         ty=1;     end
                if BW(tx,ty)>0
                    newselect(count,:)=select(it,:);   % puntos dentro de la ROI
                    count=count+1;
                end
            end
            
            clear data BW
        end
        
        function figurapoints = plotpoints(app, x, y, fr, handles)
            
            figurapoints = figure('Name','Pointillist image','Toolbar','figure');
            %px_mu = str2num(get(handles.szpx,'String'));
            ax = gca;
            ax.Box = 'on';
            ax.BoxStyle = 'full';
            ax.XTickLabel = {''};
            ax.YTickLabel = {''};
            hold on
            
            % set boundaries
            if isfield(handles,'xlim')
                %     disp('using updated axes limits...');
                [xmin,xmax] = deal(handles.xlim(1),handles.xlim(2));
                [ymin,ymax] = deal(handles.ylim(1),handles.ylim(2));
            else
                %disp('using default axes limits');
                xmin = min(x); xmax = max(x);
                ymin = min(y); ymax = max(y);
            end
            axis equal;
            pointsize = str2num(get(handles.dotsize,'String'));
            xlim([xmin xmax]); ylim([ymin ymax]);
            %Nfr = max(fr);
            
            color = 'k';
            hold on;
            
            %aux = find(fr<=Nfr);
            xlabel('X (mu)');
            ylabel('Y (mu)');
            %handles.pointsplotted =  plot(x,y,'.','MarkerSize',pointsize,'Color',color);
            plot(x,y,'.','MarkerSize',pointsize,'Color',color);
            %title([' Total nb of positions =',num2str(length(x(aux)))]);
            title([' Total nb of positions =',num2str(length(x))]);
        end
        
        function [x, y, alpha, fr] = removealpha(app, vect2remove, x, y, alpha, fr)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% Removing unneeded points from column vectors
            
               % disp(['removing ',num2str(length(vect2remove)),' elements from column vectors ..']);
                x(vect2remove) = [];
                y(vect2remove) = [];
                if nargin>3
                    alpha(vect2remove) = [];
                    fr(vect2remove) = [];
                else
                    alpha=[];
                    fr=[];
                end
        end
        
        function vect2remove = selectremovealpha(app, handles, x, y, alpha)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %% Remove points with alphas outside specified percentile range
            
            vect2remove = [];
            
            %% Remove points at x=y=0
            aux1 = find(x==0);
            aux2 = find(y==0);
            vect2remove = intersect(aux1,aux2);
            if exist('Xmatrix','var')
                matrix2remove = flagmatrixelements(app, matrix2remove,vect2remove);
            end
              %  disp(['flagging ',num2str(length(vect2remove)),' points with x=y=0 for removal!']);
            
            %% Remove points with alpha=0
            %if get(handles.alphazeroradiobutton,'Value')
                aux = find(alpha==0);
               % disp(['flagging ',num2str(length(aux)),' points with alpha=0 for removal!']);
                vect2remove = [vect2remove; aux];
                if exist('Xmatrix','var')
                    matrix2remove = flagmatrixelements(app, matrix2remove,vect2remove);
                end
            %end
            
            %if get(handles.keeppointsalpharadiobutton,'Value')
                pctmin = str2num(get(handles.alpha1,'String'));
                pctmax = str2num(get(handles.alpha2,'String'));
                if exist('Xmatrix','var')
                    aux = alphamatrix(:);
                else
                    aux = alpha;
                end
                aux = aux(aux>0);
                alphamin = prctile(aux,pctmin);
                alphamax = prctile(aux,pctmax);
            
                aux = union(find(alpha<alphamin),find(alpha>alphamax));
              %  disp([num2str(length(aux)),' points with alpha outside range ',num2str(alphamin),'-',num2str(alphamax),' removed']);
                vect2remove = [vect2remove; aux];
        end
        
        function sideviewsradiobutton_Callback(app, hObject, eventdata, handles)
            code=get(hObject, 'value');
            if code==1;
                set(handles.perspectiveradiobutton,'Value',0);
            end
        end
        
        function ys = smooth_ch(app, y, span, Nmin)
            % Smoothing
            
            ys = movingwindowfilter(app, y,span,Nmin,@median);
        end
        
        function withgaussiansmooth_CreateFcn(app, hObject, ~, ~)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function zoomfactorz_CreateFcn(app, hObject, ~, ~)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function zrange_CreateFcn(app, hObject, ~, ~)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            %componentData = load('SuperRes_v4_App.mat');
            
            % Set component properties that require runtime configuration
            %app.uipanel4.UserData = componentData.uipanel4.UserData;
            %app.uipanel7.UserData = componentData.uipanel7.UserData;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function SuperRes_v4_OpeningFcn(app, varargin)
            % --- Executes just before SuperRes_v4 is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            handles.gaussfitradiobutton=1;
            handles.muradiobutton=0;
            handles.fireradiobutton=1;
            handles.grayradiobutton=0;
            
            handles.output = hObject;
            set(handles.selectfilepushbutton,'userdata',[]);
            %set(handles.filename2,'Value',0)
            handles.filename2=0;
            
            handles.zoom.x=[];
            handles.merge=0;
            %val=get(handles.uipanel4,'userdata');


            guidata(hObject, handles);
        end

        % Button pushed function: calibratepushbutton
        function calibratepushbutton_Callback(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            [options] =calibratePALM (handles);
            
            set(handles.alphavalue,'String',options(1));
            set(handles.slider3,'value',options(1)/2000); %%%%%%%%!!!!! at max alpha!!!!!!!!!!!
            
            set(handles.threshold,'String',options(2));
            set(handles.windsize,'String',options(3));
            set(handles.gaussrad,'String',options(4));
            set(handles.defloops,'String',options(5));
            
            guidata(gcbo,handles) ;
        end

        % Menu selected function: colorshift
        function colorshift_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            CorrectColorShift
        end

        % Menu selected function: correctdetections
        function correctdetections_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            correctdetectionsmenu
        end

        % Menu selected function: driftcorrection
        function driftcorrection_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %driftcorrection
            CorrectDrift
        end

        % Button pushed function: filemergepushbutton
        function filemergepushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % load data merge
            px_mu = str2num(get(handles.szpx,'String'));
            sigmaloc = str2num(get(handles.widthgaussiansmooth,'String'));
            dx = str2num(get(handles.PALMszpx,'String'));
            %mu=get(handles.muradiobutton,'Value');
            mu=handles.muradiobutton;

            
            control=0;
            [filename, pathname] = uigetfile('*.*','Select .mat or .tif file for merging');
            if filename==0
                handles.ffname2 = [];
                handles.merge=0;
                %set(handles.filename2,'String',' ')
                app.filename2.Text='';

                %set(handles.filename2,'Value',0)
                handles.filename2=0;

               % set(handles.fireradiobutton,'Enable','on') % no LUT
               % set(handles.grayradiobutton,'Enable','on') % no LUT
                app.LUThotCheckBox.Enable='on';
                app.LUTgrayCheckBox.Enable='on';
                return
            end
            
            mensaje=msgbox('Please wait');
            ffname2 = fullfile(pathname,filename);
            [pathstr, name, ext] = fileparts(ffname2);
            
            if strcmp(ext,'.mat')
                % data : x,y, fr, alpha, radius
               % [x,y, fr, alpha, radius,sigma, blink, ratio, z,test1,test2, ffname2,filename,control]=openPALMdata(app, handles,px_mu,1,ffname2);
                [x,y, fr, alpha, radius,sigma, blink, ratio, z,test1,test2, ffname2,filename,control]=openPALMdata(handles,px_mu,1,ffname2);
            
                control=1;
            elseif strcmp(ext,'.tif')
                [~,datamatrix] = tifdataread(ffname2);
                datamatrix=double(datamatrix.data);
                control=2;
            
                %%%%%%%%%% ATT no 3D possible!!!
               % set(handles.show3Dradiobutton,'Value','0')
               % set(handles.show3Dradiobutton,'Enable','off')
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            end
            
            handles.ffname2 = ffname2;
            disp(' ');
            disp('Merging with:');
            disp(['File ',ffname2]);
            
            %set(handles.filename2,'String',filename)
            %set(handles.filename2,'Value',1)
            app.filename2.Text=filename;
            handles.filename2=1;
            
            %set(handles.fireradiobutton,'Enable','off') % no LUT
            %set(handles.grayradiobutton,'Enable','off') % no LUT
            app.LUThotCheckBox.Enable=0; % no LUT
            app.LUTgrayCheckBox.Enable=0; % no LUT
            
            handles.merge=1;
            
            if control==1
            
                % Storing
                handles.x2 = x;
                handles.y2 = y;
                handles.alphaf2 = alpha;
                handles.fr2 = fr;
                handles.radius2=radius;
                handles.sigma2=sigma;
                handles.blink2=blink;
                handles.ratio2=ratio;
                handles.z2=z;
                handles.test12=test1;
                handles.test22=test2;
            
                handles.datamatrix =[];
            
                clear x y alpha fr radius
            
            elseif control==2 %.tif
            
                % rendered first image to convert size fluo image
                [I,rendx, rendy]=makerendered2(handles,px_mu,sigmaloc,dx,mu);
            
                sizexSR=size(datamatrix,1)*px_mu/dx;
                sizeySR=size(datamatrix,2)*px_mu/dx;
            
                %    figure
                %   imshow(I,'InitialMagnification','fit')
                %   figure
                %   stackmin=min(min(datamatrix));
                 %  stackmax=max(max(datamatrix));
                 %  imshow(datamatrix,[stackmin stackmax],'InitialMagnification','fit')
            
                if sizexSR>size(I,1)*2 % arbitrary limit!!!! to verify if datamatrix is in PALM pixels...
                    aux=datamatrix;
                else
                    aux =imresize(datamatrix,[sizexSR,sizeySR]);
                end
            
                %rescale intensity
                Imin=0; Imax=1; %!!!!!!!!!!!!!!!!!!!!!
                aux = double(aux);
                minaux = min(aux(:));
                maxaux = max(aux(:));
                aux = Imin + (aux-minaux)/(maxaux-minaux)*(Imax-Imin);
            
                if size(aux,1)>size(I,1)
                    difx=size(aux,1)-size(I,1);
                    dify=size(aux,2)-size(I,2);
                    handles.datamatrix=aux(1:size(I,1)+1,dify:(size(aux,2)));
                else
                    handles.datamatrix =aux;
                end
                clear datamatrix
            
            else
                handles.merge=0;

                app.handles.filename2.Text=' ';
                handles.filename2=0;
            
                app.LUThotCheckBox.Enable=1; %  LUT possible
                app.LUTgrayCheckBox.Enable=1; %  

            
                clear     handles.x2  handles.y2 handles.alphaf2 handles.fr2 handles.radius2
            end
            
            close(mensaje)
            % Update handles structure
            guidata(gcbo,handles) ;
        end

        % Callback function
        function fuse_Callback(app, event)
            % --------------------------------------------------------------------
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            fuseSMdata
        end

        % Button pushed function: gopushbutton
        function gopushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            currentdir=cd;
            szpx=str2num(get(handles.pixel,'string'));
            
            cd(currentdir)
            
            % loop analysis
            handles.listafiles=get(handles.moviefile,'userdata');
            %currentdir=cd
            %isdir([currentdir,'\pk'])
            
             usepk=0;
            pkexists=0;
            if isdir([currentdir,'\pk']);
                pkexists=1;
                cd([currentdir,'\pk'])
                d=dir('*.pk*');
                st={d.name};
                name_stk=handles.listafiles{1};
                [namefile,rem]=strtok(name_stk,'.');
            
            
                if isempty(st)==0
                    for k=1:size(st,2)
                        presenciafile = strfind(st{k}, namefile);
                        if presenciafile>0
                            button = questdlg('Use previous 2D detections?','Previous analysis detected') ;
                            switch button
                                case 'Yes'
                                    usepk=1;
                               % case 'No'
                                    %usepk=0;
                            end
                        end
                    end
                end
                cd(currentdir)
            else
                mkdir([currentdir,'\pk']);
            end
            
            
            for nromovie=1:size(handles.listafiles,2)
                name_stk=handles.listafiles{nromovie};
                [namefile,rem]=strtok(name_stk,'.');
                disp(' ')
                disp('Detection of single molecules using MTT software')
                disp(' ')
            
                disp(['File ',name_stk]);
                str=['Batch: File ',name_stk,' (',num2str(nromovie),'/',num2str(size(handles.listafiles,2)),')'];
                set (handles.moviefile, 'string', str);
            
                seuil_alpha = str2num(get(handles.alphavalue,'String'));
                seuil_detec_1vue = str2num(get(handles.threshold,'String'));
                wn = str2num(get(handles.windsize, 'String'));
                r0 = str2num(get(handles.gaussrad, 'String'));
                nb_defl = str2num(get(handles.defloops,'String'));
                %activation_sig_fit=get(handles.gaussfitradiobutton, 'value');
                activation_sig_fit=handles.gaussfitradiobutton;
            
                info=imfinfo(name_stk);
            
              %  k=strfind(name_stk,'nd2');
              %  if k>0
               %     data = bfopen(name_stk);
               %     stack = data{1, 1};
               %     Nb_image_ds_stack = size(stack, 1);
               % else
                    stack=[];
                    Nb_image_ds_stack =length(info);
                    if length(info)<2
                        Nb_image_ds_stack=floor(info.FileSize/info.StripByteCounts);
                    else
                        Nb_image_ds_stack=length(info);
                    end
            
               % end
            
               % Nb_image_ds_stack =length(info);
                output_dir=[name_stk, '_', 'output'];
            
            
                % check if already detection in 2D!!!!
                pk=[];
                if usepk==1
                    cd([currentdir,'\pk'])
                    d=dir('*.pk*');
                    st={d.name};
                    if isempty(st)==0
                        for k=1:size(st,2)
                            presenciafile = strfind(st{k}, namefile);
                            if presenciafile>0
                                pk=load(st{k});
                               k=size(st,2); %found: so exit
                            end
                        end
                    end
                    cd(currentdir)
                end
            
            
                        [fr,x,y,alpha,radius, radiusmean,sigma, blink,sommevalid] = detectionMTTSR(name_stk , stack, Nb_image_ds_stack, seuil_detec_1vue, wn,r0, nb_defl, seuil_alpha, activation_sig_fit);
            
                         % clean zero values
                         %% Remove points at x=y=0
                         aux1 = find(x==0);
                         aux2 = find(y==0);
                         vect2remove = intersect(aux1,aux2);
                         if ~isempty(vect2remove)
                             x(vect2remove) = [];
                             y(vect2remove) = [];
                             alpha(vect2remove) = [];
                             fr(vect2remove) = [];
                             radius(vect2remove) = [];
                             sigma(vect2remove) = [];
                             blink(vect2remove) = [];
                         end
            
                         clear totalpk
                         totalpk(:,1)=fr;
                         totalpk(:,3)=x;  %att matrice results!!!
                         totalpk(:,2)=y;
                         totalpk(:,4)=radius*2;
                         totalpk(:,5)=alpha;
                         totalpk(:,6)=blink;
                         totalpk(:,7)=sigma;
                         totalpk(:,8)= zeros(size(fr,1)); %ratio widths
                         totalpk(:,9)= zeros(size(fr,1)); %z
                         totalpk(:,10)= zeros(size(fr,1)); %test?
                         totalpk(:,11)= zeros(size(fr,1)); %test?
            
                         path=[currentdir,'/pk'];
                         cd(path);
                         save([namefile, '.pk'], 'totalpk','-ascii');
                         cd(currentdir)
            
            
                save (['pk\',namefile,'.pk'], 'totalpk','-ascii');
            
                disp(['Mean number of valid particles: ',num2str(sommevalid/Nb_image_ds_stack)]);
                clear matrice_results
            
                 % matrice_results(1,:)=fr; %pk(:,1)
                 %  matrice_results(2,:)=y;   %pk(:,3);
                 %  matrice_results(3,:)=x;    %pk(:,2);
                 %  matrice_results(4,:)=alpha;  %pk(:,5);
                 %  matrice_results(5,:)=radius; %pk(:,4)/2;
                 %  matrice_results(6,:)=sigma;  %pk(:,7);
                 %  matrice_results(7,:)=blink; %pk(:,6);
                 %   matrice_results(8,:)=pk(:,8); %ratio
                 %   matrice_results(9,:)=pk(:,9); %z
                 %   matrice_results(10,:)=pk(:,10); %test
                 %   matrice_results(11,:)=pk(:,11); %test
            
                 matrice_results(1,:)=totalpk(:,1);
                 matrice_results(2,:)=totalpk(:,3);
                 matrice_results(3,:)=totalpk(:,2);
                 matrice_results(4,:)=totalpk(:,5);
                 matrice_results(5,:)=totalpk(:,4)/2;
                 matrice_results(6,:)=totalpk(:,7);
                 matrice_results(7,:)=totalpk(:,6);
                 matrice_results(8,:)=totalpk(:,8); %ratio
                 matrice_results(9,:)=totalpk(:,9); %z
                 matrice_results(10,:)=totalpk(:,10); %test
                 matrice_results(11,:)=totalpk(:,11); %test
            
                 save([namefile, '.mat'], 'matrice_results');
            
                 disp(['Results saved in ', namefile,'.mat and ', namefile,'.pk']);
            
                 disp(' ')
                clear global matrice_results pk totalpk
            end
            
            if activation_sig_fit && radiusmean~=0
                set(handles.gaussrad, 'String', num2str(radiusmean))
            elseif activation_sig_fit==0
                set(handles.gaussrad, 'String', num2str(r0))
            end
        end

        % Menu selected function: help
        function help_Callback(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            currentfolder=cd;
            p=path;
            logical=1;
            control=0;
            while logical
                [token, remain] = strtok(p,';');
                k=strfind(token,'SuperRes_v2\help');
                if isempty(k)==0
                    helpfolder=token;
                    control=1;
                    break
                end
                p=remain;
                if isempty(p)==1 || size(p,2)<13
                    control=0;
                    break
                end
            end
            
            if control>0
                cd(helpfolder);
                open('SuperRes_v2.pdf')
            end
            
            cd(currentfolder);
        end

        % Button pushed function: loadfiles
        function loadfiles_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            % folder
            path=cd;
            
            st=[];
            
            %files
            d=dir('*tif*'); % .stk files
            st={d.name};
            if isempty(st)==1
                d=dir('*nd2*'); % nikon files
                st={d.name};
                if isempty(st)==1
                    msgbox(['No files!!'],'','error');
                    return
                end
            end
            
            %choose data
            [files,v] = listdlg('PromptString','Select files:','SelectionMode','multiple','ListString',st);
            if v==0
                 return
            end
            
            [f,ultimo]=size(files);
            for i=1:ultimo
                  listafiles{i}=st{files(i)};
            end
            
            handles.file=listafiles{1};
            set(handles.moviefile,'userdata',listafiles);
            
            handles.listafiles=get(handles.moviefile,'userdata');  %selected files
            
            filename=handles.file; %first file
            if ultimo>1 %batch
              set (handles.moviefile, 'string',['Batch: File ',filename,' (1/',num2str(ultimo),')']) ;
            else
              set (handles.moviefile, 'string',handles.file) ;
            end
            
            %pushbuttons & radiobuttons
            set (handles.calibratepushbutton, 'Enable','on');
            guidata(gcbo,handles) ;
        end

        % Button pushed function: pointillisticpushbutton
        function pointillisticpushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            szpx = str2num(get(handles.szpx,'String'));
           % filename1=get(handles.filename1,'String');
           % filename2=get(handles.filename2,'String');
            filename1=app.filename1.Text;
            filename2=app.filename2.Text;

            if isempty(app.filename2.Text)==1
                merge=0;
            else
                merge=1;
            end
            
            %mu=get(handles.muradiobutton,'Value');
            mu=handles.muradiobutton;

            dx = str2num(get(handles.PALMszpx,'String'));
            %show3D=get(handles.show3Dradiobutton,'Value');
            %zoom=get(handles.zoompushbutton,'Value');
            
            % image positions
            if ~isfield(handles,'x')
                errordlg('No position data ! Select a file first.');
                return;
            end
            pointsize = str2num(get(handles.dotsize,'String'));
            
           % if show3D==1
           %     pointillistic3D(handles)
           %     return
           % end
            
            %if zoom==0
            if isempty(handles.zoom.x)==1
                X_mu=handles.x;
                Y_mu=handles.y;
                alpha=handles.alpha;
                fr=handles.fr;
               % if show3D==1
                %    Z_mu=handles.z;
                %else
                    Z_mu=[];
               % end
            else
                X_mu=handles.zoom.x;
                Y_mu=handles.zoom.y;
                alpha=handles.zoom.alpha;
                fr=handles.zoom.fr;
                %if show3D==1
                %    Z_mu=handles.zoom.z;
                %else
                    Z_mu=[];
                %end
            end
            
            vect2remove=selectremovealpha(app, handles,X_mu,Y_mu,alpha);
            % remove
            if ~isempty(vect2remove)
                [X_mu,Y_mu,alpha,fr]=removealpha(app, vect2remove,X_mu,Y_mu,alpha,fr);
            end
            
            %pixel size!!!!!
            if mu==1 %already in µm
            else
                X_mu=X_mu*szpx;
                Y_mu=Y_mu*szpx;
            end
            
            fig_points = figure('Name','Pointillist image','Toolbar','figure');
            axis equal;
            hold on
            set(gca,'YDir','reverse')
            xlim([0 max(X_mu)]); ylim([0 max(Y_mu)]);
            xlabel('X (mu)');
            ylabel('Y (mu)');
            
            if merge==1
                if isempty(handles.datamatrix)==0 %.tif
                    stackmin=min(min(handles.datamatrix));
                    stackmax=max(max(handles.datamatrix));
            
                   % if mu==1 % coordinates in microns
                        X_mu=X_mu/dx;
                        Y_mu=Y_mu/dx;
                        xlim([0 max(X_mu)]); ylim([0 max(Y_mu)]);
                        xlabel('X (PALM pixels)');
                        ylabel('Y (PALM pixels)');
            
                   % end
            
                    imshow(handles.datamatrix,[stackmin stackmax],'InitialMagnification','fit');
                    %color1 = 'b';
                    color1 =[0.4940 0.1840 0.5560];
            
                else %detections
                    X_mu2=handles.x2;
                    Y_mu2=handles.y2;
                    alphaf2=handles.alphaf2;
                    fr2=handles.fr2;
                    vect2remove2=selectremovealpha(app, handles,X_mu2,Y_mu2,alphaf2);
                    % remove
                    if ~isempty(vect2remove2)
                        [X_mu2,Y_mu2,alphaf2,fr2]=removealpha(app, vect2remove2,X_mu2,Y_mu2,alphaf2,fr2);
                    end
                    %pixel size!!!!!
                    if mu==1 %already in µm
                    else
                        X_mu2=X_mu2*szpx;
                        Y_mu2=Y_mu2*szpx;
                    end
            
                   % color1 = 'b';
                    color1 =[0.8500 0.3250 0.0980];
            
                   % color2 = 'r';
                    color2 = [0 0.4470 0.7410]	;
            
                end
            else
                color1 = 'k';
            end
            
            plot(X_mu,Y_mu,'.','MarkerSize',pointsize,'Color',color1);
            %handles.pointsplotted =  plot(X_mu,Y_mu,'.','MarkerSize',pointsize,'Color',color1);
            
            if merge==1 && isempty(handles.datamatrix)==1 % detections
                   % handles.pointsplotted2 =  plot(X_mu2,Y_mu2,'.','MarkerSize',pointsize,'Color',color2);
                    plot(X_mu2,Y_mu2,'.','MarkerSize',pointsize,'Color',color2);
                    legend([filename1,' in orange'],[filename2,' in blue'],'Location','southoutside','Orientation','horizontal', 'Interpreter', 'none');legend('boxoff')
               title([' Selected positions =',num2str(length(X_mu)),'(orange) and ',num2str(length(X_mu2)),'(blue)']);
            else
               title([' Selected positions =',num2str(length(X_mu))]);
            end
            hold off
            
            clear x y alpha fr
            
            % legend(forlegend);
            figure(fig_points);
            handles.fig_points = fig_points;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: renderedpushbutton
        function renderedpushbutton_Callback(app, event)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            set(handles.saveimagepushbutton,'userdata', []);
            szpx = str2num(get(handles.szpx,'String'));
           % colorcue=get(handles.fireradiobutton,'Value');
            colorcue=app.LUThotCheckBox.Value;

            if isempty(app.filename2.Text)==1
                merge=0;
            else
                merge=1;
            end

            %mu=get(handles.muradiobutton,'Value');
            mu=handles.muradiobutton;

            sigmaloc = str2num(get(handles.widthgaussiansmooth,'String'));
            dx = str2num(get(handles.PALMszpx,'String'));
           % show3D=get(handles.show3Dradiobutton,'Value');
            
            fr=handles.fr;
            
            if isempty(handles.zoom.x)==1
                X_mu=handles.x;
                Y_mu=handles.y;
                alpha=handles.alpha;
                Z_mu=handles.z;
            else
                X_mu=handles.zoom.x;
                Y_mu=handles.zoom.y;
                alpha=handles.zoom.alpha;
                Z_mu=handles.zoom.z;
            end
            dimx=max(X_mu);
            dimy=max(Y_mu);
            
            
            % Image 1 --------------------------------------------------------------
            %pixel size!!!!!
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% ATT ZOOM
            
            if mu==1 %already in µm
                xdim=ceil(max(X_mu))/dx;
                ydim=ceil(max(Y_mu))/dx;
            else
                xdim=ceil(max( X_mu))*szpx/dx;
                ydim=ceil(max( Y_mu))*szpx/dx;
                X_mu =  X_mu*szpx;
                Y_mu =  Y_mu*szpx;
            end
            alpha(1:10);
            gausssmooth = 1;
            auxstring = get(handles.renderedpushbutton,'String'); %%%%%%%%%%%%%%%%%
            
            vect2remove=selectremovealpha(app, handles,X_mu,Y_mu,alpha);
            
            %if show3D==0
            
                % remove
                if ~isempty(vect2remove)
                    [X_mu,Y_mu,alpha,fr]=removealpha(app, vect2remove,X_mu,Y_mu,alpha,fr);
                end
            
                % Create rendered image
                maxx=ceil(max(X_mu/dx));
                maxy=ceil(max(Y_mu/dx));
            
                [I,xx1,yy1] = PALM_rendering3(X_mu,Y_mu,alpha,sigmaloc,dx,0,xdim, ydim, gausssmooth);


            if size(I,2)>maxx
                I=I(:,1:maxx); %elimination black pixels
            end
            if size(I,1)>maxy
                I=I(1:maxy,:); %elimination black pixels
            end
            
            % Normalize image to maximum intensity of first frame or to user-defined saturation intensity
         %   if isfield(handles,'Imax')
         %       Imax1 = handles.Imax;
          %      I = I/Imax1;
         %   end
            
            %---------------------------------------------------------
            %---------------------------------------------------------
            
            if merge==1
               if isempty(handles.datamatrix)==0 %.tif
                  I2=handles.datamatrix;
               else
            
                % Image 2 -----------------------------------------------------------
            
            
                X_mu2=handles.x2;
                Y_mu2=handles.y2;
                alphaf2=handles.alphaf2;
                fr2=handles.fr2;
                vect2remove2=selectremovealpha(app, handles,X_mu2,Y_mu2,alphaf2);
                % remove
                if ~isempty(vect2remove2)
                    [X_mu2,Y_mu2,alphaf2,fr2]=removealpha(app, vect2remove2,X_mu2,Y_mu2,alphaf2,fr2);
                end
            
                %pixel size!!!!!
                if mu==1 %already in µm
                else
                    X_mu2=X_mu2*szpx;
                    Y_mu2=Y_mu2*szpx;
                end
            
                %% Create rendered image
                maxx2=ceil(max(X_mu2/dx));
                maxy2=ceil(max(Y_mu2/dx));
            
                [I2,xx12,yy12] = PALM_rendering3( X_mu2,Y_mu2,alphaf2,sigmaloc,dx,0,xdim, ydim, gausssmooth);
            
                if size(I2,2)>maxx
                    I2=I2(:,1:maxx); %elimination black pixels
                end
                if size(I2,1)>maxy
                    I2=I2(1:maxy,:); %elimination black pixels
                end
           
               end %type of file
            end
            
            handles.fig_render{1} = figure('Name','PALM rendered image');
            
            % save data rendered in .rnd file
            [pathstr, name, ext] = fileparts(handles.ffname);
            currentdir=cd;
            
            %Plot
            
            if merge==0
               % imshow(I,'InitialMagnification','fit')
                     %image_ch(app, xx1,yy1,I,[0 1]); % image_ch(xx1,yy1,I);
                     
                imagesc(I,'x',[1 size(I,2)],'y',[size(I,1) 1]); 
                %colorbar;
                
                if colorcue==1
                    colormap(hot);            
                    colorbar;
                elseif colorcue==0
                    colormap(gray)
                end

                %xlabel('X (mu)');    ylabel('Y (mu)');    
                axis equal xy;
                if exist('forlegend','var')
                    text(xmin,ymin ,forlegend{it},'Color','y','VerticalAlignment','bottom');
                end
                title(['PALM rendered image with N=',num2str(length(X_mu)),' points']);
                xlim([0 size(I,2)]); ylim([0 size(I,1)]);

                
                if exist('I','var') 
                    imcontrast(gca);
                end
                filename=[name,'.rnd'];
                
            else % merge==1
            
                if size(I2,1)>size(I,1)
                    auxiliar=[];
                    auxiliar=I2(1:size(I,1),:);
                    I2=auxiliar;
                    clear auxiliar
                end
                if size(I2,2)>size(I,2)
                    auxiliar=[];
                    auxiliar=I2(:,1:size(I,2));
                    I2=auxiliar;
                    clear auxiliar
                end
                
                Imerge=cat(3,I,zeros(size(I,1),size(I,2)),I2) ;
                
                imshow(Imerge,'InitialMagnification','fit')
                % image_ch(app, xx1,yy1,Imerge,[0 1]); % image_ch(xx1,yy1,I);
            
                %xlabel('x (mu)');    ylabel('y (mu)');    axis equal xy;
                if exist('forlegend','var')
                    text(xmin,ymin ,forlegend{it},'Color','y','VerticalAlignment','bottom');
                end
                title(['PALM rendered image with N=',num2str(length(X_mu)),' points']);
                xlim([0 size(Imerge,2)]); ylim([0 size(Imerge,1)]);

                filename=[name,'-merged.rnd'];
                
            end
            

            alphamin = str2num(get(handles.alpha1,'String'));
            alphamax = str2num(get(handles.alpha2,'String'));
            
            xdim=size(I,1);
            ydim=size(I,2);
            
            % Save rendered data
            
            if isdir('rendered'); else mkdir('rendered'); end
            cd('rendered')
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% ATT 3D!!!!
            % VOIR%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            rendx=X_mu/dx;
            rendy=Y_mu/dx;
            rendy=abs(rendy-(max(rendy)))+1;
            handles.rendx=rendx;
            handles.rendy=rendy;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            save(filename,'xdim', 'ydim','rendx','rendy','alpha','fr','alphamin','alphamax','dx','gausssmooth','sigmaloc','-mat');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            cd(currentdir)
            set(handles.renderedpushbutton,'String',auxstring);
            set(handles.renderedpushbutton,'userdata',I);
            
            clear X_mu Y_mu alpha fr rendx rendy
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: saveimagepushbutton
        function saveimagepushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %filename=get(handles.filename1,'string');
            filename=app.filename1.Text;
            namefile=strtok(filename,'.');
            szpx=str2num(get(handles.szpx,'string'));
            PALMszpx=str2num(get(handles.PALMszpx,'string'));
            merge= handles.merge;
            %mu=get(handles.muradiobutton,'Value');
            mu=handles.muradiobutton;
       
            
            if isfield(handles,'fig_render')
            else
               renderedpushbutton_Callback(hObject,eventdata , handles)
            end
            fig = handles.fig_render{1};
            aux= findobj(fig,'Type','Axes');
            if length(aux)>1 % makes sure we don't use the colorbar
                if  strcmp(get(aux(1),'Tag'),'Colorbar')
                    aux = aux(2);
                else
                    aux = aux(1);
                end
            end
            aux1 = get(aux,'CLim');
            
            if isempty(aux1)==0
                Imax = aux1(2);
                for iframe = 1:length(handles.fig_render)
                    aux4 = findobj(handles.fig_render{iframe},'Type','image'); % look for image in figure axes corresponding to frame # iframe
                    if length(aux4)>1, % makes sure we take the image and not the colorbar by looking for the largest image data
                        for iaux = 1:length(aux4)
                            aux5(iaux) = prod(size(get(aux4(iaux),'CData')));
                        end
                        [aux6 iauxmax] = max(aux5);
                        aux4 = aux4(iauxmax);
                    end
                    I = get(aux4,'CData');
            
                    % normalize the image according to the colorbar and invert Y-axis
                  %  I = I(end:-1:1,:)/Imax;
                    I = I/Imax;
            
                   if isempty(handles.zoom.x)==0 %zoom
                      [namefile,~] = uiputfile([namefile,'-zoom'],'Save zoomed image as');
                   end
                    if merge==0
                        Savename1=[namefile,'-rend.tif'];
                    else
                        Savename1=[namefile,'-merged-rend.tif'];
                    end
            
                    imwrite(I,Savename1,'tif','Compression','none');  % save the image to the disk
                    disp(['Rendered image saved as ',Savename1,' normalized to the maximum intensity ',num2str(Imax)]);
            
                end
            else
                msgbox('Create a rendered image first','Error','error')
            end
            
            clear iI I
        end

        % Button pushed function: savematpushbutton
        function savematpushbutton_Callback(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % save .mat file
            currentdir=cd;
            [pathstr, name, ext] = fileparts(handles.ffname);
            
            
            if isempty(handles.zoom.x)==1 % Full image
                x=handles.x;
                y=handles.y;
                alpha=handles.alpha;
                fr=handles.fr;
                radius=handles.radius;
                sigma=handles.sigma;
                blink=handles.blink;
                ratio=handles.ratio;
                z=handles.z;
                test1=handles.test1;
                test2=handles.test2;
            
                def_name=[name,'-clean.mat'];
            
            else %zoom
            
                x=handles.zoom.x;
                y=handles.zoom.y;
                alpha=handles.zoom.alpha;
                fr=handles.zoom.fr;
                radius=handles.zoom.radius;
                sigma=handles.zoom.sigma;
                blink=handles.zoom.blink;
                ratio=handles.zoom.ratio;
                z=handles.zoom.z;
                test1=handles.zoom.test1;
                test2=handles.zoom.test2;
            
                def_name=[name,'-zoomclean.mat'];
            
            end
            
            %% Removing unneeded points from column vectors
            vect2remove=selectremovealpha(app, handles,x,y,alpha);
            if ~isempty(vect2remove)
                disp(['Saving new .mat data. Removing ',num2str(length(vect2remove)),' elements from column vectors ...']);
                x(vect2remove) = [];
                y(vect2remove) = [];
                alpha(vect2remove) = [];
                fr(vect2remove) = [];
                radius(vect2remove) = [];
                sigma(vect2remove) = [];
                blink(vect2remove) = [];
                ratio(vect2remove) = [];
                z(vect2remove) = [];
                test1(vect2remove) = [];
                test2(vect2remove) = [];
            end
            
            cd(pathstr);
            [filename,path] = uiputfile(def_name,'Save image data as :');
            cd(path)
            
            matrice_results(1,:)=fr;
            matrice_results(3,:)= x;
            matrice_results(2,:)= y;
            matrice_results(4,:)=alpha;
            matrice_results(5,:)=radius;
            matrice_results(6,:)=sigma;
            matrice_results(7,:)=blink;
            matrice_results(8,:)=ratio;
            matrice_results(9,:)=z;
            matrice_results(10,:)=test1;
            matrice_results(11,:)=test2;
            
            save(filename, 'matrice_results');
            
            disp('File saved');
            cd(currentdir)
            clear x y alpha fr
        end

        % Button pushed function: selectfilepushbutton
        function selectfilepushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % load data % simplified version
            aux0 = get(handles.selectfilepushbutton,'String');
            px_mu = str2num(get(handles.szpx,'String'));
            %mu=get(handles.muradiobutton,'Value');
            mu=handles.muradiobutton;

            %set(handles.zoompushbutton,'Value',0);
            handles.zoom.x=[];
            set(handles.zoompushbutton,'String','ROI to zoom','FontWeight','Normal');
            
            % data : x,y, fr, alpha, radius
         %   [x,y, fr, alpha, radius,sigma, blink, ratio, z,test1,test2, ffname,filename,control]=openPALMdata(app, handles,px_mu,0);
            [x,y, fr, alpha, radius,sigma, blink, ratio, z,test1,test2, ffname,filename,control]=openPALMdata(handles,px_mu,0);
            
            set(handles.selectfilepushbutton,'userdata',filename);
            handles.ffname = ffname;
            
            disp(' ');
            disp('Visualization and analysis');
            disp(['File ',ffname,' loaded']);
            
            if exist('Xmatrix','var')
                Nfr = size(Xmatrix,2);
                Npart = size(Xmatrix,1);
                aux = ~isnan(Xmatrix);
                Npositions = sum(aux(:));
            else
                Nfr = max(fr);
                Npart = NaN;
                Npositions = length(x);
            end
            
           % if isempty(z)==0
                %set(handles.show3Dradiobutton,'Value',1);
          %      set(handles.show3Dradiobutton,'Enable','on');
           % else
           %     set(handles.show3Dradiobutton,'Value',0);
           %     set(handles.show3Dradiobutton,'Enable','off');
           % end
            
            % Storing
            handles.x = x;
            handles.y = y;
            handles.alpha = alpha;
            handles.fr = fr;
            handles.radius=radius;
            handles.sigma=sigma;
            handles.blink=blink;
            handles.ratio=ratio;
            handles.z=z;
            handles.test1=test1;
            handles.test2=test2;
            
            clear x y alpha fr radius
            
            % update GUI
            set(handles.selectfilepushbutton,'String',aux0);
            app.filename1.Text=filename;
            %set(handles.filename1,'String',filename);
            
            % Update handles structure
            guidata(gcbo,handles) ;
        end

        % Button pushed function: zoompushbutton
        function zoompushbutton_Callback(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %zoomexists=get(handles.zoompushbutton,'Value')
            %mu=get(handles.muradiobutton,'Value');
            mu=handles.muradiobutton;

            szpx = str2num(get(handles.szpx,'String'));
            dx = str2num(get(handles.PALMszpx,'String'));
            merge= handles.merge;
            
            if isempty(handles.zoom.x)==0; % Full image
            
                set(handles.zoompushbutton,'String','ROI to zoom','FontWeight','Normal');
                handles.zoom.x=[];
            
            else % select ROI
            
                % image positions
                if ~isfield(handles,'x')
                    errordlg('No position data ! Select a file first.');
                    return;
                end
            
                X_mu=handles.x;
                Y_mu=handles.y;
                alpha=handles.alpha;
                fr=handles.fr;
                Z_mu=handles.z;
            
                xdim=max(X_mu);
                ydim=max(Y_mu);
            
                vect2remove=selectremovealpha(app, handles,X_mu,Y_mu,alpha);
            
                % remove for visualization
                if ~isempty(vect2remove)
                    X_mu(vect2remove) = [];
                    Y_mu(vect2remove) = [];
                    alpha(vect2remove) = [];
                    fr(vect2remove) = [];
                    Z_mu(vect2remove) = [];
                end
            
                if merge==1
            
                    if isempty(handles.datamatrix)==0 %.tif
                        stackmin=min(min(handles.datamatrix));
                        stackmax=max(max(handles.datamatrix));
            
                        X_mu=X_mu/dx;
                        Y_mu=Y_mu/dx;
                        xlim([0 max(X_mu)]); ylim([0 max(Y_mu)]);
            
                        imshow(handles.datamatrix,[stackmin stackmax],'InitialMagnification','fit');
                        color1 = 'b';
            
                    else %detections
            
                        X_mu2=handles.x2;
                        Y_mu2=handles.y2;
                        alphaf2=handles.alphaf2;
                        fr2=handles.fr2;
                        Z_mu2=handles.z2;
            
                        % remove
                        vect2remove2=selectremovealpha(app, handles,X_mu2,Y_mu2,alphaf2);
            
                        if ~isempty(vect2remove2)
                            X_mu2(vect2remove2) = [];
                            Y_mu2(vect2remove2) = [];
                            alphaf2(vect2remove2) = [];
                            fr2(vect2remove2) = [];
                            Z_mu2(vect2remove2) = [];
                        end
            
                        %pixel size!!!!!
            
                        if mu==1 %already in µm
                        else
                            X_mu2=X_mu2*szpx;
                            Y_mu2=Y_mu2*szpx;
                            Z_mu2=Z_mu2*szpx;
                        end
            
                        color1 = 'b';
                        color2 = 'r';
            
                    end
                else
                    color1 = 'k';
                end
            
                pointsize = str2num(get(handles.dotsize,'String'));
            
                % figure
                fig_points = figure('Name','Select the region to zoom in','Toolbar','figure');
                axis equal;
                hold on
                set(gca,'YDir','reverse')
                xlim([0 max(X_mu)]); ylim([0 max(Y_mu)]);
                xlabel('X (SR pixels)');
                ylabel('Y (SR pixels)');
            
                I=ones(ceil(max(Y_mu)),ceil(max(X_mu)));
                imshow(I,'InitialMagnification','fit');
                hold on
                plot(X_mu,Y_mu,'.','MarkerSize',pointsize,'Color','k');
            
                [BW,x,y]=roipoly;
                %coord=[x y];
                %plot(x,y,'-r');
            
                x=round(x); %pixelisable
                y=round(y);
                width =max(x)-min(x);
                height=max(y)-min(y);
            
                % pick up ORIGINAL detections and save a new file
                X_mu=handles.x;
                Y_mu=handles.y;
                alpha=handles.alpha;
                fr=handles.fr;
                radius=handles.radius;
                sigma=handles.sigma;
                blink=handles.blink;
                ratio=handles.ratio;
                Z_mu=handles.z;
                test1=handles.test1;
                test2=handles.test2;
            
                in=inpolygon(X_mu,Y_mu,x,y); %index of X_mu,Y_mu points in polygon defined by x,y
                handles.zoom.x=X_mu(in)-min(x); %corrected for the position of the ROI
                handles.zoom.y=Y_mu(in)-min(y);
            
                if isempty(Z_mu)==0
                    handles.zoom.z=Z_mu(in);
                end
            
                handles.zoom.alpha=alpha(in);
                handles.zoom.fr=fr(in);
                handles.zoom.radius=radius(in);
                handles.zoom.sigma=sigma(in);
                handles.zoom.blink=blink(in);
                handles.zoom.ratio=ratio(in);
                handles.zoom.z=Z_mu(in);
                handles.zoom.test1=test1(in);
                handles.zoom.test2=test2(in);
            
                %if .tif image...
                %if isempty(handles.datamatrix)==0 %.tif
                %    I2 = imcrop(I, [min(x) min(y) width height] ) ;
                %else
                %  I2 = imcrop(BW, [min(x) min(y) width height] ) ;
            
                I2 = imcrop(BW, [min(y) min(x) height width] ) ;
                I2=ones(size(I2,1),size(I2,2));
                %end
            
                % figure
                fig_zoom = figure('Name','Zoomed region','Toolbar','figure');
                set(gca,'YDir','reverse')
                xlim([0 max(handles.zoom.x)]); ylim([0 max(handles.zoom.y)]);
                xlabel('X (SR pixels)');
                ylabel('Y (SR pixels)');
                axis equal;%axis ij
                hold on
            
                % ATT MERGE!!!
               % imshow(I2,'InitialMagnification','fit');
                plot(handles.zoom.x,handles.zoom.y,'.','MarkerSize',pointsize,'Color','k');
            
                %set(handles.zoompushbutton,'String','Full image','FontWeight','Bold');
                set(handles.zoompushbutton,'String','Full image');
            
            end
            
            % Update handles structure
            guidata(gcbo,handles) ;
        end

        % Value changed function: WidthfittingCheckBox
        function WidthfittingCheckBoxValueChanged(app, event)
            value = app.WidthfittingCheckBox.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            handles.gaussfitradiobutton=value;

        end

        % Value changed function: DatainmCheckBox
        function DatainmCheckBoxValueChanged(app, event)
            value = app.DatainmCheckBox.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
            handles.muradiobutton=value;

        end

        % Value changed function: LUThotCheckBox
        function LUThotCheckBoxValueChanged(app, event)
            value = app.LUThotCheckBox.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            app.LUTgrayCheckBox.Value=0;
            handles.fireradiobutton=value;

        end

        % Value changed function: LUTgrayCheckBox
        function LUTgrayCheckBoxValueChanged(app, event)
            value = app.LUTgrayCheckBox.Value;
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            app.LUThotCheckBox.Value=0;
            handles.grayradiobutton=value;
            
        end

        % Menu selected function: SegmentationMenu
        function SegmentationMenuSelected(app, event)
            
            % Diinamic
            Segment

        end

        % Menu selected function: ClusteringMenu
        function ClusteringMenuSelected(app, event)
            
            % Diinamic
            Cluster

        end

        % Menu selected function: ColocalizationMenu
        function ColocalizationMenuSelected(app, event)
            
          CoLocalize
        end

        % Button pushed function: loadpkpushbutton
        function loadpkpushbuttonButtonPushed(app, event)
            
            handles.T = str2num(app.avtimewindow.Value);             % Averaging time window (frames)
            handles.T_off = str2num(app.blkprob.Value)*-1;            % Blinking probability (0 to 1)
            handles.Dmax = str2num(app.maxD.Value);                % Max diffusion coefficient (um^2/s)  
            handles.Boule_free = str2num(app.resdiam.Value);          % Research diameter (pixels) 
            handles.Nb_combi = str2num(app.maxcomp.Value);            % Max competitors             
            handles.Poids_melange_diff = str2num(app.weightD.Value);  % Weight max local diffusion (0 to 1)
            handles.szpx = str2num(app.pxsz.Value);                % Pixel size (nm)
            handles.till = str2num(app.till.Value);                % Time between frames (ms)

            handles.blinktime = str2num(app.maxblktime.Value);    % Max blinking time (frames)
            handles.blinkdist = str2num(app.maxdist.Value);    % Max distance during blinking (pixels)
            handles.limdist = str2num(app.maxdisttlag1.Value);      % Maximum distance for tlag=1 (pixels)
            handles.mintrace = str2num(app.minlength.Value);     % Minimum length of trajectories:


            buildTracksSuperRes(handles)

        end

        % Button pushed function: ReconnectonlyButton
        function ReconnectonlyButtonPushed(app, event)

            
             %window
            blinktime=str2num(app.maxblktime.Value); %str2num to pick up multiple values
            blinkdist=str2num(app.maxdist.Value);
            limdist=str2double(app.maxdisttlag1.Value);
            mintrace = str2double(app.minlength.Value); 

            currentdir=cd;
            cd([currentdir,'/trc'])

            %trc file
            % dialog box to select files
            d=dir('*.trc');
            st = {d.name};
            [listafiles,v] = listdlg('PromptString','Select files:','SelectionMode','multiple','ListString',st);
            if v==0
                msgbox('Currentfolder must contain a trc folder with initial tracking data')
                return
            end

            % loop over files
            for cont=1:size(listafiles,2)
                
                disp('Doing reconnection alone')
                file=[st{listafiles(cont)}] 
                [namefile,rem]=strtok(file,'.');    
                trc = load(file);
                reconnectedfile = trc;
    
                if size(blinktime,2)>1    
                    
                    %series of reconnections
                    number=size(blinktime,2);
                    series=1;   
                    while series<number
                        blink=blinktime(series);
                        distmax=blinkdist(series);
                        disp('  ');
                        disp('Reconnecting trajectories...');
                        waitbarhandle=waitbar( 0,'Please wait...','Name','Reconnecting trajectories');
                        reconnectedfile=reconnectfasttrc(reconnectedfile,blink,distmax,3,limdist,waitbarhandle);
                        series=series+1;
                        close(waitbarhandle);
                    end
                    
                    % last one: uses minpoints from the window
                    blink=blinktime(series);
                    distmax=blinkdist(series);
                    disp('  ');
                    disp('Reconnecting trajectories...');
                    waitbarhandle=waitbar( 0,'Please wait...','Name','Reconnecting trajectories');
                    reconnectedfile=reconnectfasttrc(reconnectedfile,blink,distmax,mintrace,limdist,waitbarhandle);
                    close(waitbarhandle);
                else
                    %one reconnection
                    disp('  ');
                    disp('Reconnecting trajectories...');
                    waitbarhandle=waitbar( 0,'Please wait...','Name','Reconnecting trajectories');
                    reconnectedfile=reconnectfasttrc(trc,blinktime,blinkdist,mintrace,limdist,waitbarhandle);
                    close(waitbarhandle);
                end
    
                if isempty(reconnectedfile)==0 
                    %save everything
                     % save(['trc/',namefile,'.con.trc'],'reconnectedfile','-ascii');
                    save([namefile,'.con.trc'],'reconnectedfile','-ascii');
                else
                    disp('Empty .con.trc file');
                end
            
            end %%for loop files

        cd(currentdir)
        clear trc reconnected file 

        disp('Done')
        disp(' ')

        %clear all

        end

        % Button pushed function: compile
        function compilediffpushbutton(app, event)
            
            compileindivSM

        end

        % Button pushed function: trajindivpushbutton
        function analysediffpushbuttonButtonPushed(app, event)
            
            %  parameters from window
            mintrace=str2double(app.minpointsD.Value);
           % maxtlagmsd=str2double(app.maxtlagmsd.Value);
            interv=str2double(app.windowPc.Value);
            maxmsd=str2double(app.maxtlagmsd.Value)*2; % 2 times the number of points to represent
            shortraj=1;

            trcparam(1)=str2double(app.till.Value);    % till
            trcparam(2)=str2double(app.pxsz.Value);       % szpx

            % selects data

            dialog_title='Select data folder';
            diffupath = uigetdir(cd,dialog_title);
            if diffupath==0
                return
            end
            currentdir=cd;
            
            cd([diffupath,'\trc']);
            d=dir('*.trc*');
            st = {d.name};
            if isempty(st)==1
                msgbox('Wrong folder','error','error')
                return
            end

            prompt = {'Time between images (ms):','Pixel size (nm):'};
            num_lines= 1;
            dlg_title = 'Reading .trc files';
            def = {num2str(trcparam(1)),num2str(trcparam(2))}; % default values
            answer  = inputdlg(prompt,dlg_title,num_lines,def);
            exit=size(answer);
            if exit(1) == 0;
                return; 
            end
            trcparam(1)=str2num(answer{1});     % till
            trcparam(2)=str2num(answer{2});        % szpx


            %set(handles.gopushbutton,'userdata',trcparam);

            [listafiles,v] = listdlg('PromptString','Select files:','SelectionMode','multiple','ListString',st);
            if v==0
                return
            end

            cd(currentdir)

            if isdir('diff\'); else; mkdir('diff\'); end

            disp(' ')
            disp('Diffusion calculation on individual trajectories')
            disp(' ')

            for nromovie=1:size(listafiles,2)
    
                cd([diffupath,'/trc']);
                trclength=[];

                file=st{listafiles(nromovie)};
                disp(' ')
                disp(['File ',file]);
              %  set (handles.textfiles, 'string',[file,' (',num2str(nromovie),'/',num2str(col),')']) ;
                waitbarhandle=waitbar( 0,'Please wait...','Name',['Analyzing trajectories in ',file]);
                
                [namefile,rem]=strtok(file,'.');
                traces=load(file);
                Te=trcparam(1);
                a=trcparam(2);
                Nz=max(traces(:,2));

                cd(currentdir)
                
                if isempty(traces)==0
                    %data(1).peri=peritype;
       
                    data(1).szpx=a;
                    data(1).till=Te;
                    data(1).frames=Nz;
                    nb_fit=5;  %!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
                    counttraces=0;
                    cd([diffupath,'\diff\']);    
       
                    for i=1:max(traces(:,1))   
                        areatrajsyn=[];
                        areatrajextra=[];
                        
                        if exist('waitbarhandle')
                            waitbar(i/max(traces(:,1)),waitbarhandle,['Trajectory # ',num2str(i)]);
                        end
                        index=find(traces(:,1)==i); %all the points for present trajectory
           
                        if isempty(index)==0
                            if size(index,1)>mintrace
                                disp(' ')
                                disp(['Trajectory # ',num2str(i)])
                                trc=traces(index,:); % trajectory to analyze
                   
                                trclength=[trclength; size(trc,1)];
                                
                                if maxmsd>size(trc,1)
                                    maxmsdtraj=size(trc,1)-1;
                                else
                                    maxmsdtraj=maxmsd;
                                end
                                
                                % MSD and D all
                                msddata=[];
                                [D,b,MSD]=calculMSD(trc,a,Te,nb_fit,maxmsdtraj); % tlag max calculated!!!!!!!!!!!
                                msddata=[MSD.time_lag MSD.rho MSD.sigma];  
                   
                                % segments by localization
                                countsyn=0;
                                counttotal=max(trc(:,2))-trc(1,2);
                                countsegm=0;
                                inifin=[];
                   
                                if size(trc,2)==5 %no localization data
                                    trc=[trc zeros(size(trc,1),1)]; % out of domains by definition
                                    cut.nrosegm=1;
                                    cut.data=trc;
                                    cut.segment(1).data=trc;
                       
                                else %loc syn
                                    tri=cutbyloctrcSM(trc, mintrace, 0);    %cut.nrosegm;  %cut.data=trc;   %cut.segment(order).data
                                    cut=tribyloc(tri,1);
                   
                                end
                                count=1;
                                for k=1:cut.nrosegm
                                    lengthsegm=size(cut.segment(k).data,1);
                       
                                    if lengthsegm >mintrace
                                       
                                       % MSD and D
                                       if maxmsd>size(cut.segment(k).data,1)
                                           maxmsdtraj=size(cut.segment(k).data,1)-1;
                                       else
                                           maxmsdtraj=maxmsd;
                                       end

                                       [Dk,bk,MSDk]=calculMSD(cut.segment(k).data,a,Te,nb_fit,maxmsdtraj);
                                       msddatak=[MSDk.time_lag MSDk.rho MSDk.sigma];
                                       traj.segm(count).msd=msddatak;
                                       traj.segm(count).D=Dk;   
                                       traj.segm(count).b=bk;
                                       traj.segm(count).data=cut.segment(k).data;
                                       count=count+1;
                                       
                                      %area per trajectory
                                       [kk,areatraj] = convhull(cut.segment(k).data(:,3)*a/1000,cut.segment(k).data(:,4)*a/1000);
                                       if cut.segment(k).data(1,6)>0 
                                           areatrajsyn=[areatrajsyn; nromovie i k size(cut.segment(k).data,1) areatraj areatraj/size(cut.segment(k).data,1)];
                                       else
                                           if cut.segment(k).data(1,6)==0
                                               areatrajextra=[areatrajextra; nromovie i k size(cut.segment(k).data,1) areatraj areatraj/size(cut.segment(k).data,1)];
                                           else
                                               if perival==1
                                                   areatrajsyn=[areatrajsyn; nromovie i k size(cut.segment(k).data,1) areatraj areatraj/size(cut.segment(k).data,1)];
                                               else
                                                   areatrajextra=[areatrajextra; nromovie i k size(cut.segment(k).data,1) areatraj areatraj/size(cut.segment(k).data,1)];
                                               end
                                           end
                                       end
                                       
                                       %Pc on segment
                                       fractal=calcfillingSM(cut.segment(k).data,interv,mintrace,a);
                                       
                                       if shortraj==1                   % one value of Pc(mean)per trajectory (short trajectories)
                                           if isempty(fractal)==0
                                               traj.segm(count).fill=[0 fractal(1,2) mean(fractal(:,3)) mean(fractal(:,4)) mean(fractal(:,5))];
                                           else
                                               traj.segm(count).fill=[];
                                           end
                         
                                       else
                                           traj.segm(count).fill=fractal;
                                       end 
                                       
                                   end %size segment
                               end % loop segm   
                               
                               traj.nrosegm=count-1;
                               disp([num2str(count-1),' segment(s)']);
                        
                                %Pc on trajectory
                                fractal=calcfillingSM(trc,interv,mintrace,a);
                          
                               % traj
                               traj.coord=trc;
                               traj.msd=msddata;
                               traj.D=D;
                               traj.fill=fractal;
                               traj.areatrajsyn=areatrajsyn;
                               traj.areatrajextra=areatrajextra;
                               
                               traj.length=trclength;
                               counttraces=counttraces+1;
                               data(i).traj=traj;
                               
                               clear trc msddata nuevo traj
                           end % mintrace        
                       end % index
                   end % trace

                   cd([diffupath,'/diff']);

                   data(1).nrotraj=counttraces;
                   save([namefile,'.tns'],'data','-mat');
                   close(waitbarhandle);

                   cd(currentdir)
                   clear data
               end %empty traces
            end % files

            disp(' ')
            disp('Done')

            clear trc analyse res M msddata nuevo

            cd(currentdir)
        end

        % Menu selected function: CheckqualityMenu
        function CheckqualityMenuSelected(app, event)
            
             % Create GUIDE-style callback args - Added by Migration Tool
          %  [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            QualityCheck


        end

        % Button pushed function: localization
        function localizationButtonPushed(app, event)
            
            % localization with respect to segmented image
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
            localizeSM(handles)
            
        end

        % Button pushed function: visualize
        function visualizeButtonPushed(app, event)
            
          movtrackSM

        end

        % Menu selected function: SuperRes_v4suiteMenu
        function SuperRes_v4suiteMenuSelected(app, event)
            
           % looks for the path
           mfilePath = mfilename('fullpath');
           if contains(mfilePath,'LiveEditorEvaluationHelper')
               mfilePath = matlab.desktop.editor.getActiveFilename;
            end
            disp(mfilePath);
            k=strfind(mfilePath,'\');

            %opens file
            helpfile=[mfilePath(1:k(size(k,2))),'help\SuperRes_v4_App.pdf'];
            open(helpfile)

        end

        % Menu selected function: DetectionMenu
        function DetectionMenuSelected(app, event)
            

           % looks for the path
           mfilePath = mfilename('fullpath');
           if contains(mfilePath,'LiveEditorEvaluationHelper')
               mfilePath = matlab.desktop.editor.getActiveFilename;
            end
            disp(mfilePath)
            k=strfind(mfilePath,'\');

            %opens file
            helpfile=[mfilePath(1:k(size(k,2))),'help\Detection.pdf'];
            open(helpfile)

        end

        % Menu selected function: ManagedetectionsMenu
        function ManagedetectionsMenuSelected(app, event)
            

           % looks for the path
           mfilePath = mfilename('fullpath');
           if contains(mfilePath,'LiveEditorEvaluationHelper')
               mfilePath = matlab.desktop.editor.getActiveFilename;
            end
            disp(mfilePath);
            k=strfind(mfilePath,'\');

            %opens file
            helpfile=[mfilePath(1:k(size(k,2))),'help\ManageDetections.pdf'];
            open(helpfile)

        end

        % Menu selected function: SMLMdataanalysisMenu
        function SMLMdataanalysisMenuSelected(app, event)
            

           % looks for the path
           mfilePath = mfilename('fullpath');
           if contains(mfilePath,'LiveEditorEvaluationHelper')
               mfilePath = matlab.desktop.editor.getActiveFilename;
            end
            disp(mfilePath);
            k=strfind(mfilePath,'\');

            %opens file
            helpfile=[mfilePath(1:k(size(k,2))),'help\Analysis.pdf'];
            open(helpfile)

        end

        % Menu selected function: TrackinganddiffusionMenu
        function TrackinganddiffusionMenuSelected(app, event)
            

           % looks for the path
           mfilePath = mfilename('fullpath');
           if contains(mfilePath,'LiveEditorEvaluationHelper')
               mfilePath = matlab.desktop.editor.getActiveFilename;
            end
            disp(mfilePath);
            k=strfind(mfilePath,'\');

            %opens file
            helpfile=[mfilePath(1:k(size(k,2))),'help\TrackingDiffusion.pdf'];
            open(helpfile)

        end

        % Menu selected function: VisualizationMenu
        function VisualizationMenuSelected(app, event)
            

           % looks for the path
           mfilePath = mfilename('fullpath');
           if contains(mfilePath,'LiveEditorEvaluationHelper')
               mfilePath = matlab.desktop.editor.getActiveFilename;
            end
            disp(mfilePath);
            k=strfind(mfilePath,'\');

            %opens file
            helpfile=[mfilePath(1:k(size(k,2))),'help\Visualization.pdf'];
            open(helpfile)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'SuperRes_v4';
            app.figure1.Tag = 'figure1';
            app.detection = uimenu(app.figure1);
            app.detection.Text = 'Manage detections     ';
            app.detection.Tag = 'detection';
            app.CheckqualityMenu = uimenu(app.detection);
            app.CheckqualityMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckqualityMenuSelected, true);
            app.CheckqualityMenu.Separator = 'on';
            app.CheckqualityMenu.Text = 'Check quality';
            app.driftcorrection = uimenu(app.detection);
            app.driftcorrection.MenuSelectedFcn = createCallbackFcn(app, @driftcorrection_Callback, true);
            app.driftcorrection.Separator = 'on';
            app.driftcorrection.Text = 'Correct drift';
            app.driftcorrection.Tag = 'driftcorrection';
            app.colorshift = uimenu(app.detection);
            app.colorshift.MenuSelectedFcn = createCallbackFcn(app, @colorshift_Callback, true);
            app.colorshift.Text = 'Correct color shift';
            app.colorshift.Tag = 'colorshift';
            app.correctdetections = uimenu(app.detection);
            app.correctdetections.MenuSelectedFcn = createCallbackFcn(app, @correctdetections_Callback, true);
            app.correctdetections.Text = 'Correct multiple detections';
            app.correctdetections.Tag = 'correctdetections';
            app.ConcatenatedataMenu = uimenu(app.detection);
            app.ConcatenatedataMenu.Separator = 'on';
            app.ConcatenatedataMenu.Text = 'Concatenate data';
            app.AnalysisMenu = uimenu(app.figure1);
            app.AnalysisMenu.Text = 'Analysis            ';
            app.SegmentationMenu = uimenu(app.AnalysisMenu);
            app.SegmentationMenu.MenuSelectedFcn = createCallbackFcn(app, @SegmentationMenuSelected, true);
            app.SegmentationMenu.Text = 'Segmentation';
            app.ClusteringMenu = uimenu(app.AnalysisMenu);
            app.ClusteringMenu.MenuSelectedFcn = createCallbackFcn(app, @ClusteringMenuSelected, true);
            app.ClusteringMenu.Text = 'Clustering';
            app.ColocalizationMenu = uimenu(app.AnalysisMenu);
            app.ColocalizationMenu.MenuSelectedFcn = createCallbackFcn(app, @ColocalizationMenuSelected, true);
            app.ColocalizationMenu.Text = 'Colocalization';
            app.help = uimenu(app.figure1);
            app.help.MenuSelectedFcn = createCallbackFcn(app, @help_Callback, true);
            app.help.Text = 'Help        ';
            app.help.Tag = 'help';
            app.SuperRes_v4suiteMenu = uimenu(app.help);
            app.SuperRes_v4suiteMenu.MenuSelectedFcn = createCallbackFcn(app, @SuperRes_v4suiteMenuSelected, true);
            app.SuperRes_v4suiteMenu.Text = 'SuperRes_v4 suite';
            app.DetectionMenu = uimenu(app.help);
            app.DetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @DetectionMenuSelected, true);
            app.DetectionMenu.Text = 'Detection';
            app.ManagedetectionsMenu = uimenu(app.help);
            app.ManagedetectionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ManagedetectionsMenuSelected, true);
            app.ManagedetectionsMenu.Text = 'Manage detections';
            app.SMLMdataanalysisMenu = uimenu(app.help);
            app.SMLMdataanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @SMLMdataanalysisMenuSelected, true);
            app.SMLMdataanalysisMenu.Text = 'SMLM data analysis';
            app.TrackinganddiffusionMenu = uimenu(app.help);
            app.TrackinganddiffusionMenu.MenuSelectedFcn = createCallbackFcn(app, @TrackinganddiffusionMenuSelected, true);
            app.TrackinganddiffusionMenu.Text = 'Tracking and diffusion';
            app.VisualizationMenu = uimenu(app.help);
            app.VisualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @VisualizationMenuSelected, true);
            app.VisualizationMenu.Text = 'Visualization';
            app.TabGroup = uitabgroup(app.figure1);
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.selectfilepushbutton = uibutton(app.VisualizationTab, 'push');
            app.selectfilepushbutton.ButtonPushedFcn = createCallbackFcn(app, @selectfilepushbutton_Callback, true);
            app.selectfilepushbutton.Tag = 'selectfilepushbutton';
            app.selectfilepushbutton.Text = 'Select file';
            app.filemergepushbutton = uibutton(app.VisualizationTab, 'push');
            app.filemergepushbutton.ButtonPushedFcn = createCallbackFcn(app, @filemergepushbutton_Callback, true);
            app.filemergepushbutton.Tag = 'filemergepushbutton';
            app.filemergepushbutton.Text = 'Merge with';
            app.uipanel2 = uipanel(app.VisualizationTab);
            app.uipanel2.Tag = 'uipanel2';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Keep intensities between';
            app.alpha2 = uieditfield(app.uipanel2, 'text');
            app.alpha2.Tag = 'alpha2';
            app.alpha2.Value = '100';
            app.alpha1 = uieditfield(app.uipanel2, 'text');
            app.alpha1.Tag = 'alpha1';
            app.alpha1.Value = '1';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'and';
            app.savematpushbutton = uibutton(app.uipanel2, 'push');
            app.savematpushbutton.ButtonPushedFcn = createCallbackFcn(app, @savematpushbutton_Callback, true);
            app.savematpushbutton.Tag = 'savematpushbutton';
            app.savematpushbutton.Text = 'Save as .mat';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'dot size (points)';
            app.dotsize = uieditfield(app.uipanel2, 'text');
            app.dotsize.Tag = 'dotsize';
            app.dotsize.Value = '2';
            app.pointillisticpushbutton = uibutton(app.uipanel2, 'push');
            app.pointillisticpushbutton.ButtonPushedFcn = createCallbackFcn(app, @pointillisticpushbutton_Callback, true);
            app.pointillisticpushbutton.Tag = 'pointillisticpushbutton';
            app.pointillisticpushbutton.Text = 'Pointillistic';
            app.uipanel1 = uipanel(app.VisualizationTab);
            app.uipanel1.Tag = 'uipanel1';
            app.renderedpushbutton = uibutton(app.uipanel1, 'push');
            app.renderedpushbutton.ButtonPushedFcn = createCallbackFcn(app, @renderedpushbutton_Callback, true);
            app.renderedpushbutton.Tag = 'renderedpushbutton';
            app.renderedpushbutton.Text = 'Rendered';
            app.saveimagepushbutton = uibutton(app.uipanel1, 'push');
            app.saveimagepushbutton.ButtonPushedFcn = createCallbackFcn(app, @saveimagepushbutton_Callback, true);
            app.saveimagepushbutton.Tag = 'saveimagepushbutton';
            app.saveimagepushbutton.Text = 'Save image';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Sigma gaussian (µm)';
            app.widthgaussiansmooth = uieditfield(app.uipanel1, 'text');
            app.widthgaussiansmooth.Tag = 'widthgaussiansmooth';
            app.widthgaussiansmooth.Value = '0.01';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'SR pixel (µm)';
            app.PALMszpx = uieditfield(app.uipanel1, 'text');
            app.PALMszpx.Tag = 'PALMszpx';
            app.PALMszpx.Value = '0.01';
            app.text8 = uilabel(app.uipanel1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'camera pixel (µm)';
            app.szpx = uieditfield(app.uipanel1, 'text');
            app.szpx.Tag = 'szpx';
            app.szpx.Value = '0.160';
            app.LUThotCheckBox = uicheckbox(app.uipanel1);
            app.LUThotCheckBox.ValueChangedFcn = createCallbackFcn(app, @LUThotCheckBoxValueChanged, true);
            app.LUThotCheckBox.Text = 'LUT hot';
            app.LUThotCheckBox.Value = true;
            app.LUTgrayCheckBox = uicheckbox(app.uipanel1);
            app.LUTgrayCheckBox.ValueChangedFcn = createCallbackFcn(app, @LUTgrayCheckBoxValueChanged, true);
            app.LUTgrayCheckBox.Text = 'LUT gray';
            app.zoompushbutton = uibutton(app.VisualizationTab, 'push');
            app.zoompushbutton.ButtonPushedFcn = createCallbackFcn(app, @zoompushbutton_Callback, true);
            app.zoompushbutton.Tag = 'zoompushbutton';
            app.zoompushbutton.Text = 'ROI to zoom';
            app.filename1 = uilabel(app.VisualizationTab);
            app.filename1.Text = '';
            app.filename2 = uilabel(app.VisualizationTab);
            app.filename2.Text = '';
            app.DatainmCheckBox = uicheckbox(app.VisualizationTab);
            app.DatainmCheckBox.ValueChangedFcn = createCallbackFcn(app, @DatainmCheckBoxValueChanged, true);
            app.DatainmCheckBox.Text = 'Data in µm';
            app.DetectionTab = uitab(app.TabGroup);
            app.DetectionTab.Title = 'Detection';
            app.loadfiles = uibutton(app.DetectionTab, 'push');
            app.loadfiles.ButtonPushedFcn = createCallbackFcn(app, @loadfiles_Callback, true);
            app.loadfiles.Tag = 'loadfiles';
            app.loadfiles.Text = 'Load movies';
            app.gopushbutton = uibutton(app.DetectionTab, 'push');
            app.gopushbutton.ButtonPushedFcn = createCallbackFcn(app, @gopushbutton_Callback, true);
            app.gopushbutton.Tag = 'gopushbutton';
            app.gopushbutton.Text = 'Go';
            app.moviefile = uilabel(app.DetectionTab);
            app.moviefile.Tag = 'moviefile';
            app.moviefile.Text = '';
            app.text19 = uilabel(app.DetectionTab);
            app.text19.Tag = 'text19';
            app.text19.Text = 'File:';
            app.windsize = uieditfield(app.DetectionTab, 'text');
            app.windsize.Tag = 'windsize';
            app.windsize.Value = '7';
            app.text20 = uilabel(app.DetectionTab);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Size of window';
            app.text15 = uilabel(app.DetectionTab);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Threshold detection';
            app.threshold = uieditfield(app.DetectionTab, 'text');
            app.threshold.Tag = 'threshold';
            app.threshold.Value = '28';
            app.gaussrad = uieditfield(app.DetectionTab, 'text');
            app.gaussrad.Tag = 'gaussrad';
            app.gaussrad.Value = '1.1';
            app.text14 = uilabel(app.DetectionTab);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Gaussian radius';
            app.calibratepushbutton = uibutton(app.DetectionTab, 'push');
            app.calibratepushbutton.ButtonPushedFcn = createCallbackFcn(app, @calibratepushbutton_Callback, true);
            app.calibratepushbutton.Tag = 'calibratepushbutton';
            app.calibratepushbutton.Text = 'Calibrate';
            app.text21 = uilabel(app.DetectionTab);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Deflation loops';
            app.defloops = uieditfield(app.DetectionTab, 'text');
            app.defloops.Tag = 'defloops';
            app.defloops.Value = '0';
            app.alphavalue = uieditfield(app.DetectionTab, 'text');
            app.alphavalue.Tag = 'alphavalue';
            app.alphavalue.Value = '10';
            app.text22 = uilabel(app.DetectionTab);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Intensity threshold';
            app.slider3 = uislider(app.DetectionTab);
            app.slider3.Limits = [0 1];
            app.slider3.MajorTicks = [];
            app.slider3.MinorTicks = [];
            app.slider3.Tag = 'slider3';
            app.text23 = uilabel(app.DetectionTab);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Camera pixel size (nm)';
            app.pixel = uieditfield(app.DetectionTab, 'text');
            app.pixel.Tag = 'pixel';
            app.pixel.Value = '160';
            app.WidthfittingCheckBox = uicheckbox(app.DetectionTab);
            app.WidthfittingCheckBox.ValueChangedFcn = createCallbackFcn(app, @WidthfittingCheckBoxValueChanged, true);
            app.WidthfittingCheckBox.Text = 'Width fitting';
            app.TrackinganddiffusionTab = uitab(app.TabGroup);
            app.TrackinganddiffusionTab.Title = 'Tracking and diffusion';
            app.loadpkpushbutton = uibutton(app.TrackinganddiffusionTab, 'push');
            app.loadpkpushbutton.ButtonPushedFcn = createCallbackFcn(app, @loadpkpushbuttonButtonPushed, true);
            app.loadpkpushbutton.Tag = 'loadpkpushbutton';
            app.loadpkpushbutton.Text = 'Track and reconnect';
            app.text34 = uilabel(app.TrackinganddiffusionTab);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Pixel size';
            app.pxsz = uieditfield(app.TrackinganddiffusionTab, 'text');
            app.pxsz.Tag = 'pxsz';
            app.pxsz.Value = '160';
            app.text35 = uilabel(app.TrackinganddiffusionTab);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Acquis time';
            app.till = uieditfield(app.TrackinganddiffusionTab, 'text');
            app.till.Tag = 'till';
            app.till.Value = '30';
            app.text36 = uilabel(app.TrackinganddiffusionTab);
            app.text36.Tag = 'text36';
            app.text36.Text = 'nm';
            app.text37 = uilabel(app.TrackinganddiffusionTab);
            app.text37.Tag = 'text37';
            app.text37.Text = 'ms';
            app.text38 = uilabel(app.TrackinganddiffusionTab);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Av time window';
            app.maxD = uieditfield(app.TrackinganddiffusionTab, 'text');
            app.maxD.Tag = 'maxD';
            app.maxD.Value = '1';
            app.text40 = uilabel(app.TrackinganddiffusionTab);
            app.text40.Tag = 'text40';
            app.text40.Text = ' Max D (µm2/s)';
            app.maxcomp = uieditfield(app.TrackinganddiffusionTab, 'text');
            app.maxcomp.Tag = 'maxcomp';
            app.maxcomp.Value = '3';
            app.text42 = uilabel(app.TrackinganddiffusionTab);
            app.text42.Tag = 'text42';
            app.text42.Text = ' Max compet';
            app.blkprob = uieditfield(app.TrackinganddiffusionTab, 'text');
            app.blkprob.Tag = 'blkprob';
            app.blkprob.Value = '1';
            app.text39 = uilabel(app.TrackinganddiffusionTab);
            app.text39.Tag = 'text39';
            app.text39.Text = 'Blinking prob';
            app.resdiam = uieditfield(app.TrackinganddiffusionTab, 'text');
            app.resdiam.Tag = 'resdiam';
            app.resdiam.Value = '1';
            app.text41 = uilabel(app.TrackinganddiffusionTab);
            app.text41.Tag = 'text41';
            app.text41.Text = 'Res diam (px)';
            app.weightD = uieditfield(app.TrackinganddiffusionTab, 'text');
            app.weightD.Tag = 'weightD';
            app.weightD.Value = '1';
            app.text43 = uilabel(app.TrackinganddiffusionTab);
            app.text43.Tag = 'text43';
            app.text43.Text = 'Weight local D';
            app.uipanel9 = uipanel(app.TrackinganddiffusionTab);
            app.uipanel9.Title = 'Reconnection';
            app.uipanel9.Tag = 'uipanel9';
            app.text47 = uilabel(app.uipanel9);
            app.text47.Tag = 'text47';
            app.text47.Text = ' Max. dist tlag=1';
            app.maxdisttlag1 = uieditfield(app.uipanel9, 'text');
            app.maxdisttlag1.Tag = 'maxdisttlag1';
            app.maxdisttlag1.Value = '3';
            app.text46 = uilabel(app.uipanel9);
            app.text46.Tag = 'text46';
            app.text46.Text = ' Min length (points)';
            app.minlength = uieditfield(app.uipanel9, 'text');
            app.minlength.Tag = 'minlength';
            app.minlength.Value = '3';
            app.text45 = uilabel(app.uipanel9);
            app.text45.Tag = 'text45';
            app.text45.Text = ' Max. distance (px)';
            app.maxdist = uieditfield(app.uipanel9, 'text');
            app.maxdist.Tag = 'maxdist';
            app.maxdist.Value = '3';
            app.text44 = uilabel(app.uipanel9);
            app.text44.Tag = 'text44';
            app.text44.Text = ' Max. blink time (frames)';
            app.maxblktime = uieditfield(app.uipanel9, 'text');
            app.maxblktime.Tag = 'maxblktime';
            app.maxblktime.Value = '3';
            app.ReconnectonlyButton = uibutton(app.uipanel9, 'push');
            app.ReconnectonlyButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectonlyButtonPushed, true);
            app.ReconnectonlyButton.Text = 'Reconnect only';
            app.avtimewindow = uieditfield(app.TrackinganddiffusionTab, 'text');
            app.avtimewindow.Tag = 'avtimewindow';
            app.avtimewindow.Value = '3';
            app.DiffusionanalysisPanel = uipanel(app.TrackinganddiffusionTab);
            app.DiffusionanalysisPanel.Title = 'Diffusion analysis';
            app.trajindivpushbutton = uibutton(app.DiffusionanalysisPanel, 'push');
            app.trajindivpushbutton.ButtonPushedFcn = createCallbackFcn(app, @analysediffpushbuttonButtonPushed, true);
            app.trajindivpushbutton.Tag = 'trajindivpushbutton';
            app.trajindivpushbutton.Text = 'Analyse';
            app.compile = uibutton(app.DiffusionanalysisPanel, 'push');
            app.compile.ButtonPushedFcn = createCallbackFcn(app, @compilediffpushbutton, true);
            app.compile.Tag = 'trajindivpushbutton';
            app.compile.Text = 'Summary';
            app.text38_2 = uilabel(app.DiffusionanalysisPanel);
            app.text38_2.Tag = 'text38';
            app.text38_2.Text = 'Min points';
            app.maxtlagmsd = uieditfield(app.DiffusionanalysisPanel, 'text');
            app.maxtlagmsd.Tag = 'maxD';
            app.maxtlagmsd.Value = '20';
            app.text40_2 = uilabel(app.DiffusionanalysisPanel);
            app.text40_2.Tag = 'text40';
            app.text40_2.Text = ' Max tlag MSD';
            app.minpointsD = uieditfield(app.DiffusionanalysisPanel, 'text');
            app.minpointsD.Tag = 'avtimewindow';
            app.minpointsD.Value = '5';
            app.text38_3 = uilabel(app.DiffusionanalysisPanel);
            app.text38_3.Tag = 'text38';
            app.text38_3.Text = 'Window Pc';
            app.windowPc = uieditfield(app.DiffusionanalysisPanel, 'text');
            app.windowPc.Tag = 'avtimewindow';
            app.windowPc.Value = '5';
            app.localization = uibutton(app.TrackinganddiffusionTab, 'push');
            app.localization.ButtonPushedFcn = createCallbackFcn(app, @localizationButtonPushed, true);
            app.localization.Tag = 'trajindivpushbutton';
            app.localization.Text = 'Localize';
            app.visualize = uibutton(app.TrackinganddiffusionTab, 'push');
            app.visualize.ButtonPushedFcn = createCallbackFcn(app, @visualizeButtonPushed, true);
            app.visualize.Tag = 'trajindivpushbutton';
            app.visualize.Text = 'Visualize';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SuperRes_v4_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)SuperRes_v4_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end